classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        howManyB     matlab.ui.control.NumericEditField
        SpinnerB     matlab.ui.control.Spinner
        SpinnerA     matlab.ui.control.Spinner
        howManyA     matlab.ui.control.NumericEditField
        sensorePeso  matlab.ui.control.Image
    end

    
    properties (Access = public)
        %% Oggetti
        objects;
        weightSensor;
        
        %% Timer
        masterTimer;
        spawnTimer;

        %% Modbus
        mb;
        plc_connected = false;
        
        %% Labels
        types = ["A", "B", "C", "NotValid"];
        
        %% Variables
        toAddA;
        toAddB;
        watchNextB=true;
        watchNextA = true;

    lineABlocked = false;
    end
    
    methods (Access = public)
        %% Master Loop
        function masterLoop(app)

            if app.plc_connected
                try
                    % Scorro su tutti gli oggetti dell'array objects
                    for i = length(app.objects):-1:1
                        obj = app.objects{i};
                        app.update(obj);
                        if obj.shouldDelete
                            delete(obj.graphic);
                            app.objects(i) = [];
                            disp(app.objects)
                        end
                    end
                catch e
                    disp(['Errore lettura coil: ' e.message]);
                end
            end
        end

        %% Funzione per generare gli oggetti
         function spawnObject(app)
            % Tipo casuale
            value = rand;
            if value < 0.33
                % Peso viti: 1,5 desiderato, variazioni di 0,003 per ogni
                % vite in più o in meno
                weight = 1.491 + rand() * 0.018;
                color = [1 0 0];
            elseif value < 0.66 && value > 0.33
                weight = 2.49 + rand() * 0.02;
                color = [0 1 0];
            else 
                weight = 3 + rand() * 0.5;
                color = [0 0 1];
                
            end
                       
            % Creazione grafica
            objGraphic = uilabel(app.UIFigure);     
            objGraphic.BackgroundColor = color;
            objGraphic.Position = [-20 400 40 40];
               
            % Creazione oggetto logico
            obj = IndustrialObject(weight, objGraphic);
               
            % Salvataggio nell'array
            app.objects{end+1} = obj;
               
            disp("Nuovo oggetto creato:")
            disp(weight)
         end

        %% Aggiornamento di tutti gli object (forse conviene metterlo dentro la classe IndustrialObject?)
        function update(app, obj)
            % Controllo basato sul tipo (Non categorizzato, A//B/C)
            switch obj.type
                case "NotClassified"
                    obj.move(10, 0)
                    if obj.getX() > app.sensorePeso.Position(1) && obj.getX() < app.sensorePeso.Position(1) + 11
                        app.writeReal(2049, obj.getWeight());
                        obj.setType("WaitingBalanceAndClassification");
                    end
                    %sono su sensore di peso
                case "WaitingBalanceAndClassification"
                    if obj.waitingTime > 10
                        valid = read(app.mb, "coils", 1, 1);
                        if valid
                            type = read(app.mb, "holdingregs", 1, 1);
                            obj.setType(app.types(type));
                            obj.setState("Classified")
                        else 
                            obj.setType("Discarded")
                            obj.setState("Discarded")
                        end
                        obj.waitingTime = 0;
                    else 
                        obj.waitingTime=obj.waitingTime+1;
                    end
                    

                case "A"
                    app.updateA(obj);

                case "B"
                    app.updateB(obj);

                case "C"
                    app.updateC(obj);

                case "Discarded"
                    obj.move(10, 0)
                    if obj.getX() > app.sensorePeso.Position(1) + 150
                        obj.shouldDelete = true;
                    end
            end
        end
        % La funzione lineABlocked impone che se un oggetto si trova nello
        % stato di modifing,lineABlocked risulta true, quindi la catena A si ferma, 
      
        function updateA(app, obj)
            
            switch obj.state
                case "Classified"
                    obj.move(-15, -4);
                    raw = read(app.mb, "holdingregs", 2, 1);
                    isNegative = int16(bitget(raw, 16));
                    app.toAddA = int16(bitset(raw, 16, 0)) + (-2^15) * isNegative;
                    app.howManyA.Value = double(app.toAddA);
                    if obj.getX() < 100
                        obj.setState("Preparing");
                    end

                case "Preparing"
                    if ~app.lineABlocked
                        obj.move(0, -10);
                    end
                    if app.watchNextA
                        if app.SpinnerA.Value < app.toAddA
                            app.SpinnerA.Value = app.SpinnerA.Value + 1;
                        elseif app.SpinnerA.Value > app.toAddA
                            app.SpinnerA.Value = app.SpinnerA.Value - 1;
                        else
                            app.watchNextA = false;
                            obj.setState("MovingToStation");
                        end
                    end

                    %questa era una prova di uno stato intermedio per
                    %fermare solo quelli che si muovono da preparing a
                    %modifing, solo che ipoteticamente gli oggetti si
                    %bloccavano troppo
                case "MovingToStation"
                     obj.move(0, -15);
                        if obj.getY() < app.SpinnerA.Position(2)
                            app.lineABlocked = true;
                            obj.setState("Modifing");
                        end

                case "Modifing"
                     if obj.waitingTime > 10
                        app.SpinnerA.Value = 0;
                        app.watchNextA = true;
                        app.lineABlocked = false;
                        obj.setState("Finished");
                        obj.waitingTime = 0;
                     else
                         obj.waitingTime = obj.waitingTime + 1;
                     end

                case "Finished"
                    obj.move(0, -15);
                    if obj.getY() < 0
                        obj.shouldDelete = true;
                    end
            end
        end


        function updateB(app, obj)
            switch obj.state
                case "Classified"
                    obj.move(0,-4);
                    raw = read(app.mb, "holdingregs", 3, 1);
                    isNegative = int16(bitget(raw, 16));
                    app.toAddB = int16(bitset(raw, 16, 0)) + (-2^15) * isNegative;
                    app.howManyB.Value = double(app.toAddB);
                    if obj.getX() < 100
                        obj.setState("Preparing");
                    end

                case "Preparing"
                    if ~app.lineBBlocked
                        obj.move(0,-10);
                    end
                    if app.watchNextB
                        if app.SpinnerB.Value < app.toAddB
                            app.SpinnerB.Value = app.SpinnerB.Value + 1;
                        elseif app.SpinnerB.Value > app.toAddB
                            app.SpinnerB.Value = app.SpinnerB.Value - 1;
                        else
                            app.watchNextB = false;
                            obj.setState("MovingToStation");
                        end
                    end

                    %questa era una prova di uno stato intermedio per
                    %fermare solo quelli che si muovono da preparing a
                    %modifing, solo che ipoteticamente gli oggetti si
                    %bloccavano troppo
                case "MovingToStation"
                     obj.move(0,-4);
                        if obj.getY() < app.SpinnerB.Position(2)
                            app.lineBBlocked = true;
                            obj.setState("Modifing");
                        end

                case "Modifing"
                     if obj.waitingTime > 10
                        app.SpinnerB.Value = 0;
                        app.watchNextB = true;
                        app.lineBBlocked = false;
                        obj.setState("Finished");
                        obj.waitingTime = 0;
                     else
                         obj.waitingTime = obj.waitingTime + 1;
                     end

                case "Finished"
                    obj.move(0,-4);
                    if obj.getY() < 0
                        obj.shouldDelete = true;
                    end
            end
        end


        function updateC(~, obj)
            switch obj.state
                case "Classified"
                    obj.move(10,-4);
                    
            end
        end
        %% Utilities
        % Funzione per leggere un valore reale da openplc
        function value = readReal(app, address)
            regs = read(app.mb, "holdingregs", address, 2);        
            u32 = bitshift(uint32(regs(1)),16) + uint32(regs(2));        
            value = typecast(u32,'single');        
        end
        function writeReal(app, address, value)
        u32 = typecast(single(value), 'uint32');      
            regs = double([ ...
                bitshift(u32,-16), ...
                bitand(u32,65535)
            ]);      
            write(app.mb, "holdingregs", address, regs);      
        end
                
        %% Start Simulation
        function startSimulation(app)
           if ~app.plc_connected
                try 
                    app.mb = modbus('tcpip', '127.0.0.1', 502);
                    app.plc_connected = true;
                catch e
                    disp(e.message)
                    app.plc_connected = false;
                end
           end
        end

        %% Stop simulation
        function stopSimulation(app)
            % Ferma il master timer
            if ~isempty(app.masterTimer)
                stop(app.masterTimer);
            end
            % Chiudi connessione modbus
            if app.plc_connected && ~isempty(app.mb)
                try
                    delete(app.mb)
                catch
                end
                app.plc_connected = false;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startSimulation(app);
            if isempty(app.masterTimer) || ~isvalid(app.masterTimer)
                app.masterTimer = timer( ...
                    'ExecutionMode','fixedSpacing', ...
                    'Period', 0.1, ...
                    'BusyMode','drop', ...
                    'TimerFcn',@(~,~) app.masterLoop());
            end

            app.objects = {};
            
            app.spawnTimer = timer;
            app.spawnTimer.ExecutionMode = 'fixedSpacing';
            app.spawnTimer.Period = 3;
            app.spawnTimer.TimerFcn = @(~,~) app.spawnObject();
            app.spawnTimer.ErrorFcn = @(~,e) disp(e.Data);
            app.masterTimer.ErrorFcn = @(~,e) disp(e.Data);
            
            if strcmp(app.masterTimer.Running,'off')
                start(app.masterTimer);
                start(app.spawnTimer);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.sensorePeso = uiimage(app.UIFigure);
            app.sensorePeso.ImageSource = fullfile(pathToMLAPP, 'assets', 'sensore di peso.png');
            app.howManyA = uieditfield(app.UIFigure, 'numeric');
            app.SpinnerA = uispinner(app.UIFigure);
            app.SpinnerB = uispinner(app.UIFigure);
            app.howManyB = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end